classdef AppBolaPrato < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PosioXPanel                    matlab.ui.container.Panel
        EntradaConstanteSliderLabel    matlab.ui.control.Label
        EntradaConstanteSliderX        matlab.ui.control.Slider
        AmplitudeSliderLabel           matlab.ui.control.Label
        AmplitudeSliderX               matlab.ui.control.Slider
        SwitchEntradaX                 matlab.ui.control.RockerSwitch
        ValorConstanteX                matlab.ui.control.NumericEditField
        AmpltudeSenoidalX              matlab.ui.control.NumericEditField
        FrequnciaSliderLabel           matlab.ui.control.Label
        FrequnciaSliderX               matlab.ui.control.Slider
        PertubaoPanel                  matlab.ui.container.Panel
        SliderX                        matlab.ui.control.Slider
        EixoXLabel                     matlab.ui.control.Label
        SliderY                        matlab.ui.control.Slider
        EixoYLabel                     matlab.ui.control.Label
        ModelagemdeGanhosPanel         matlab.ui.container.Panel
        AjusteGanhosAlocaodePolosKnob  matlab.ui.control.Knob
        AjusteGanhosAlocaodePolosKnobLabel  matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        ModoClssicoLampLabel           matlab.ui.control.Label
        ModoClssicoLamp                matlab.ui.control.Lamp
        ModoLQRLampLabel               matlab.ui.control.Label
        ModoLQRLamp                    matlab.ui.control.Lamp
        AjusteGanhosLQRKnobLabel       matlab.ui.control.Label
        AjusteGanhosLQRKnob            matlab.ui.control.Knob
        ControledaPosiodaBolasobreoPratoPanel  matlab.ui.container.Panel
        PosioYPanel                    matlab.ui.container.Panel
        SwitchEntradaY                 matlab.ui.control.RockerSwitch
        ValorConstanteY                matlab.ui.control.NumericEditField
        AmpltudeSenoidalY              matlab.ui.control.NumericEditField
        EntradaConstanteSlider_2Label  matlab.ui.control.Label
        EntradaConstanteSliderY        matlab.ui.control.Slider
        AmplitudeSlider_2Label         matlab.ui.control.Label
        AmplitudeSliderY               matlab.ui.control.Slider
        FrequnciaSliderLabel_2         matlab.ui.control.Label
        FrequnciaSliderY               matlab.ui.control.Slider
        FormasEspeciaisPanel           matlab.ui.container.Panel
        Circulo                        matlab.ui.control.Button
        Elipse                         matlab.ui.control.Button
        Quadrado                       matlab.ui.control.Button
        Triangulo                      matlab.ui.control.Button
        Infinito                       matlab.ui.control.Button
        RaioCirculo                    matlab.ui.control.NumericEditField
        EixoYElipse                    matlab.ui.control.NumericEditField
        EixoXElipse                    matlab.ui.control.NumericEditField
        EixoXInfinito                  matlab.ui.control.NumericEditField
        EixoYInfinito                  matlab.ui.control.NumericEditField
        LadoQuadrado                   matlab.ui.control.NumericEditField
        LadoTriangulo                  matlab.ui.control.NumericEditField
        RaioLabel                      matlab.ui.control.Label
        EixoXLabel_2                   matlab.ui.control.Label
        EixoYLabel_2                   matlab.ui.control.Label
        EixoYLabel_3                   matlab.ui.control.Label
        EixoXLabel_3                   matlab.ui.control.Label
        Lado2Label                     matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Define os valores do Simulink nos valores iniciais
            set_param([bdroot,'/Senoide X'],'Amplitude', '0');
            set_param([bdroot,'/Constante X'],'Value', '0');
            set_param([bdroot,'/Senoide X'],'Frequency', '1');
            set_param([bdroot,'/Senoide Y'],'Amplitude', '0');
            set_param([bdroot,'/Constante Y'],'Value', '0');
            set_param([bdroot,'/Senoide Y'],'Frequency', '1');
            set_param([bdroot,'/Senoide Y'],'Phase', '0');
            set_param([bdroot,'/On Off Senoide X'],'Gain','0');
            set_param([bdroot,'/On Off Constante X'],'Gain','1');
            set_param([bdroot,'/On Off Senoide Y'],'Gain','0');
            set_param([bdroot,'/On Off Constante Y'],'Gain','1');
            set_param([bdroot,'/Pertubação X'],'Value', '0');
            set_param([bdroot,'/Pertubação Y'],'Value', '0');
            set_param([bdroot,'/Escolha1'],'Gain', '0');
            set_param([bdroot,'/Escolha2'],'Gain', '0');
            set_param([bdroot,'/Escolha3'],'Gain', '1');
            set_param([bdroot,'/Escolha4'],'Gain', '1');
            set_param([bdroot,'/Escolha5'],'Gain', '0');
            set_param([bdroot,'/Escolha6'],'Gain', '1');
            set_param([bdroot,'/Escolha7'],'Gain', '0');
            set_param([bdroot,'/Escolha8'],'Gain', '1');
            set_param([bdroot,'/GanhoPolos1'],'Gain', '1');
            set_param([bdroot,'/GanhoPolos2'],'Gain', '1');
            set_param([bdroot,'/GanhoPolos3'],'Gain', '1');
            set_param([bdroot,'/GanhoPolos4'],'Gain', '1');
            set_param([bdroot,'/GanhoLQR'],'Gain', '1');
            set_param([bdroot,'/GanhoLQR1'],'Gain', '1');
            set_param([bdroot,'/GanhoLQR2'],'Gain', '1');
            set_param([bdroot,'/GanhoLQR3'],'Gain', '1');
            %Define visualmente os componentes do aplicativo para os valores iniciais
            app.ModoClssicoLamp.Color = [1.00,1.00,0.00];
            app.ModoLQRLamp.Color = 'k';
            app.SwitchEntradaX.Value = 'Constante';
            app.SwitchEntradaY.Value = 'Constante';
            app.ValorConstanteX.BackgroundColor = 'g';
            app.AmpltudeSenoidalX.BackgroundColor = [0.65,0.65,0.65];
            app.ValorConstanteY.BackgroundColor = 'g';
            app.AmpltudeSenoidalY.BackgroundColor = [0.65,0.65,0.65];
            app.Switch.Value = 'Polos';
            app.SliderX.Value = 0;
            app.SliderY.Value = 0;
            app.EntradaConstanteSliderX.Value = 0;
            app.EntradaConstanteSliderY.Value = 0;
            app.AmplitudeSliderX.Value = 0;
            app.AmplitudeSliderY.Value = 0;
            app.FrequnciaSliderX.Value = 1;
            app.FrequnciaSliderY.Value = 1;
            app.ValorConstanteX.Value = 0;
            app.ValorConstanteY.Value = 0;
            app.AmpltudeSenoidalX.Value = 0;
            app.AmpltudeSenoidalY.Value = 0;
            app.AjusteGanhosAlocaodePolosKnob.Value = 1;
            app.AjusteGanhosLQRKnob.Value = 1;

        end

        % Callback function: EntradaConstanteSliderX, ValorConstanteX
        function EntradaConstanteSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Constante X'],'Value', num2str(changingValue));
            app.ValorConstanteX.Value=changingValue;
        end

        % Callback function: AmplitudeSliderX, AmpltudeSenoidalX
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Senoide X'],'Amplitude', num2str(changingValue));
            app.AmpltudeSenoidalX.Value=changingValue;
        end

        % Value changing function: FrequnciaSliderX
        function FrequnciaSliderXValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Senoide X'],'Frequency', num2str(changingValue));
        end

        % Value changed function: SwitchEntradaX
        function SwitchEntradaXValueChanged(app, event)
            value = app.SwitchEntradaX.Value;
            
            if strcmp(value,'Constante')
                set_param([bdroot,'/On Off Senoide X'],'Gain','0');
                set_param([bdroot,'/On Off Constante X'],'Gain','1');
                app.ValorConstanteX.BackgroundColor = 'g';
                app.AmpltudeSenoidalX.BackgroundColor = [0.65,0.65,0.65];
            elseif strcmp(value,'Oscilatória')
                set_param([bdroot,'/On Off Senoide X'],'Gain','1');
                set_param([bdroot,'/On Off Constante X'],'Gain','0');
                app.ValorConstanteX.BackgroundColor = [0.65,0.65,0.65];
                app.AmpltudeSenoidalX.BackgroundColor = 'g';
            end
        end

        % Value changing function: SliderX
        function PertubacaoSliderXValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Pertubação X'],'Value', num2str(changingValue));
        end

        % Value changing function: AjusteGanhosLQRKnob
        function AjusteGanhosLQRKnobValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/GanhoLQR'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoLQR1'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoLQR2'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoLQR3'],'Gain', num2str(changingValue));
        end

        % Value changing function: AjusteGanhosAlocaodePolosKnob
        function AjusteGanhosAlocaodePolosKnobValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/GanhoPolos1'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoPolos2'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoPolos3'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoPolos4'],'Gain', num2str(changingValue));
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp (app.Switch.Value,'LQR')
                set_param([bdroot,'/Escolha1'],'Gain', '1');
                set_param([bdroot,'/Escolha2'],'Gain', '1');
                set_param([bdroot,'/Escolha3'],'Gain', '0');
                set_param([bdroot,'/Escolha4'],'Gain', '0');
                set_param([bdroot,'/Escolha5'],'Gain', '1');
                set_param([bdroot,'/Escolha6'],'Gain', '0');
                set_param([bdroot,'/Escolha7'],'Gain', '1');
                set_param([bdroot,'/Escolha8'],'Gain', '0');
                app.ModoLQRLamp.Color = [1.00,1.00,0.00];
                app.ModoClssicoLamp.Color = 'k';
            else
                set_param([bdroot,'/Escolha1'],'Gain', '0');
                set_param([bdroot,'/Escolha2'],'Gain', '0');
                set_param([bdroot,'/Escolha3'],'Gain', '1');
                set_param([bdroot,'/Escolha4'],'Gain', '1');
                set_param([bdroot,'/Escolha5'],'Gain', '0');
                set_param([bdroot,'/Escolha6'],'Gain', '1');
                set_param([bdroot,'/Escolha7'],'Gain', '0');
                set_param([bdroot,'/Escolha8'],'Gain', '1');
                app.ModoClssicoLamp.Color = [1.00,1.00,0.00];
                app.ModoLQRLamp.Color = 'k';                
            end    
            
        end

        % Value changing function: SliderY
        function SliderYValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Pertubação Y'],'Value', num2str(changingValue));
        end

        % Value changed function: SwitchEntradaY
        function SwitchEntradaYValueChanged(app, event)
            value = app.SwitchEntradaY.Value;
            
            if strcmp(value,'Constante')
                set_param([bdroot,'/On Off Senoide Y'],'Gain','0');
                set_param([bdroot,'/On Off Constante Y'],'Gain','1');
                app.ValorConstanteY.BackgroundColor = 'g';
                app.AmpltudeSenoidalY.BackgroundColor = [0.65,0.65,0.65];
            elseif strcmp(value,'Oscilatória')
                set_param([bdroot,'/On Off Senoide Y'],'Gain','1');
                set_param([bdroot,'/On Off Constante Y'],'Gain','0');
                app.ValorConstanteY.BackgroundColor = [0.65,0.65,0.65];
                app.AmpltudeSenoidalY.BackgroundColor = 'g';
            end
        end

        % Value changing function: FrequnciaSliderY
        function FrequnciaSliderYValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Senoide Y'],'Frequency', num2str(changingValue));
        end

        % Value changing function: AmplitudeSliderY
        function AmplitudeSliderYValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Senoide Y'],'Amplitude', num2str(changingValue));
            app.AmpltudeSenoidalY.Value=changingValue;
        end

        % Value changing function: EntradaConstanteSliderY
        function EntradaConstanteSliderYValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Constante Y'],'Value', num2str(changingValue));
            app.ValorConstanteY.Value=changingValue;
        end

        % Button pushed function: Circulo
        function CirculoButtonPushed(app, event)
            startupFcn(app)
            value=app.RaioCirculo.Value;
            %Definir as fases
            set_param([bdroot,'/Senoide Y'],'Phase', 'pi/2');
            %Colocar entrada senoidal
            set_param([bdroot,'/On Off Senoide Y'],'Gain','1');
            set_param([bdroot,'/On Off Constante Y'],'Gain','0');
            set_param([bdroot,'/On Off Senoide X'],'Gain','1');
            set_param([bdroot,'/On Off Constante X'],'Gain','0');
            %Definir Frequência
            set_param([bdroot,'/Senoide X'],'Frequency', '1');
            set_param([bdroot,'/Senoide Y'],'Frequency', '1');
            %Definir Valor do raio
            set_param([bdroot,'/Senoide X'],'Amplitude', num2str(value));
            set_param([bdroot,'/Senoide Y'],'Amplitude', num2str(value));
            

        end

        % Button pushed function: Elipse
        function ElipseButtonPushed(app, event)
            startupFcn(app)
            %Definir os semieixos
            valueX=app.EixoXElipse.Value;
            valueY=app.EixoYElipse.Value;
            %Definir as fases
            set_param([bdroot,'/Senoide Y'],'Phase', 'pi/2');
            %Colocar entrada senoidal
            set_param([bdroot,'/On Off Senoide Y'],'Gain','1');
            set_param([bdroot,'/On Off Constante Y'],'Gain','0');
            set_param([bdroot,'/On Off Senoide X'],'Gain','1');
            set_param([bdroot,'/On Off Constante X'],'Gain','0');
            %Definir Frequência
            set_param([bdroot,'/Senoide X'],'Frequency', '1');
            set_param([bdroot,'/Senoide Y'],'Frequency', '1');
            %Definir Valor do raio
            set_param([bdroot,'/Senoide X'],'Amplitude', num2str(valueX));
            set_param([bdroot,'/Senoide Y'],'Amplitude', num2str(valueY));
        end

        % Button pushed function: Infinito
        function InfinitoButtonPushed(app, event)
            startupFcn(app)
            valueX=app.EixoXInfinito.Value;
            valueY=app.EixoYInfinito.Value;
            %Colocar entrada senoidal
            set_param([bdroot,'/On Off Senoide Y'],'Gain','1');
            set_param([bdroot,'/On Off Constante Y'],'Gain','0');
            set_param([bdroot,'/On Off Senoide X'],'Gain','1');
            set_param([bdroot,'/On Off Constante X'],'Gain','0');
            %Definir Frequência
            set_param([bdroot,'/Senoide X'],'Frequency', '1');
            set_param([bdroot,'/Senoide Y'],'Frequency', '2');
            %Definir Valor do raio
            set_param([bdroot,'/Senoide X'],'Amplitude', num2str(valueX));
            set_param([bdroot,'/Senoide Y'],'Amplitude', num2str(valueY));   
        end

        % Button pushed function: Quadrado
        function QuadradoButtonPushed(app, event)
            startupFcn(app)
            k = app.LadoQuadrado.Value;
                           % Time,   EixoX,  EixoY,   EixoZ
            trajectoryInfo = [0,     0,      0,       0; ... % Initial position
                              0.05,  0.05*k, 0.05*k,  0; ...
                              0.1,   0.1*k,  0.1*k,   0; ...
                              0.25,  0.5*k,  0.5*k,   0; ...
                              0.45,  0.9*k,  0.9*k,   0; ...
                              0.5,   0.95*k, 0.95*k,  0; ...
                              %Aresta inferior direita
                              0.55,  1*k,    1*k,     0; ...                              
                              0.6,   1*k,    0.95*k,  0; ...
                              0.65,  1*k,    0.9*k,   0; ...
                              1,     1*k,    0,       0; ...
                              1.4,   1*k,    -0.9*k,  0; ...
                              1.45,  1*k,    -0.95*k, 0; ...  
                              1.5,   1*k,    -1*k,    0; ...
                              %Aresta inferior esquerda
                              1.55,  0.95*k, -1*k,    0; ...
                              1.6,   0.9*k,  -1*k,    0; ...
                              2,     0,      -1*k,    0; ...
                              2.4,   -0.9*k, -1*k,    0; ...
                              2.45,  -0.95*k,-1*k,    0; ...
                              2.5,   -1*k,   -1*k,    0; ...
                              %Aresta superior esquerda
                              2.55,  -1*k,   -0.95*k, 0; ...
                              2.6,   -1*k,   -0.9*k,  0; ...
                              3,     -1*k,   0,       0; ...
                              3.4,   -1*k,   0.9*k,   0; ...
                              3.45,  -1*k,   0.95*k,  0; ...
                              3.5,   -1*k,   1*k,     0; ...
                              %Aresta superior direita
                              3.55,  -0.95*k,1*k,     0; ...
                              3.6,   -0.9*k, 1*k,     0; ...
                              4,     0,      1*k,     0; ...
                              4.4,   0.9*k,  1*k,     0; ...
                              4.45,  0.95*k, 1*k,     0; ...
                              4.5,   1*k,    1*k,     0];    %Final Position                                 
            trajectory = waypointTrajectory(trajectoryInfo(:,2:4),'TimeOfArrival',trajectoryInfo(:,1));
            count = 1;
            trajectory.SampleRate = 75;
            while ~isDone (trajectory)
                [currentPosition] = trajectory();
                set_param([bdroot,'/Constante X'],'Value', num2str(currentPosition(1)));
                set_param([bdroot,'/Constante Y'],'Value', num2str(currentPosition(2)));
                pause(trajectory.SamplesPerFrame/trajectory.SampleRate)
                count = count + 1;
            end
            hold off
        end

        % Button pushed function: Triangulo
        function TrianguloButtonPushed(app, event)
            startupFcn(app)
            k = app.LadoTriangulo.Value;
                           % Time,   EixoX,  EixoY,   EixoZ
            trajectoryInfo = [0,     0,      0,       0; ... % Initial position
                              0.05,  0,      0.05*k,  0; ...
                              0.1,   0,      0.1*k,   0; ...
                              0.25,  0,      0.5*k,   0; ...
                              0.45,  0,      0.95*k,  0; ...
                              0.5,   0,      1*k,     0; ...
                              
                              0.55,  0.1*k,  0.95*k,  0; ...                              
                              0.6,   0.15*k, 0.9*k,   0; ...
                              0.65,  0.2*k,  0.8*k,   0; ...
                              1,     0.5*k,  0,       0; ...
                              1.4,   0.9*k,  -0.8*k,  0; ...
                              1.45,  0.95*k, -0.9*k,  0; ...  
                              1.5,   1*k,    -1*k,    0; ...
                              
                              1.55,  0.95*k, -1*k,    0; ...
                              1.6,   0.9*k,  -1*k,    0; ...
                              2,     0,      -1*k,    0; ...
                              2.4,   -0.9*k, -1*k,    0; ...
                              2.45,  -0.95*k,-1*k,    0; ...
                              2.5,   -1*k,   -1*k,    0; ...
                              
                              2.55,  -0.95*k, -0.95*k,0; ...
                              2.6,   -0.9*k,  -0.9*k, 0; ...
                              3,     -0.5*k,  0,      0; ...
                              3.4,   -0.15*k, 0.9*k,  0; ...
                              3.45,  -0.1*k,  0.95*k, 0; ...
                              3.5,   0,       1*k,    0];   %Final Position                
                  
            trajectory = waypointTrajectory(trajectoryInfo(:,2:4),'TimeOfArrival',trajectoryInfo(:,1));
            count = 1;
            trajectory.SampleRate = 75;
            while ~isDone (trajectory)
                [currentPosition] = trajectory();
                set_param([bdroot,'/Constante X'],'Value', num2str(currentPosition(1)));
                set_param([bdroot,'/Constante Y'],'Value', num2str(currentPosition(2)));
                pause(trajectory.SamplesPerFrame/trajectory.SampleRate)
                count = count + 1;
            end
            hold off
        end

        % Callback function
        function ResetButtonPushed(app, event)
            startupFcn(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed2(app, event)
            startupFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PosioXPanel = uipanel(app.UIFigure);
            app.PosioXPanel.Title = 'Posição X';
            app.EntradaConstanteSliderLabel = uilabel(app.PosioXPanel);
            app.EntradaConstanteSliderLabel.Text = 'Entrada Constante';
            app.EntradaConstanteSliderX = uislider(app.PosioXPanel);
            app.EntradaConstanteSliderX.Limits = [-0.6 0.6];
            app.EntradaConstanteSliderX.ValueChangingFcn = createCallbackFcn(app, @EntradaConstanteSliderValueChanging, true);
            app.AmplitudeSliderLabel = uilabel(app.PosioXPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSliderX = uislider(app.PosioXPanel);
            app.AmplitudeSliderX.Limits = [-0.6 0.6];
            app.AmplitudeSliderX.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.SwitchEntradaX = uiswitch(app.PosioXPanel, 'rocker');
            app.SwitchEntradaX.Items = {'Oscilatória', 'Constante'};
            app.SwitchEntradaX.ValueChangedFcn = createCallbackFcn(app, @SwitchEntradaXValueChanged, true);
            app.SwitchEntradaX.Value = 'Constante';
            app.ValorConstanteX = uieditfield(app.PosioXPanel, 'numeric');
            app.ValorConstanteX.ValueChangedFcn = createCallbackFcn(app, @EntradaConstanteSliderValueChanging, true);
            app.AmpltudeSenoidalX = uieditfield(app.PosioXPanel, 'numeric');
            app.AmpltudeSenoidalX.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.FrequnciaSliderLabel = uilabel(app.PosioXPanel);
            app.FrequnciaSliderLabel.Text = 'Frequência';
            app.FrequnciaSliderX = uislider(app.PosioXPanel);
            app.FrequnciaSliderX.Limits = [0 2];
            app.FrequnciaSliderX.ValueChangingFcn = createCallbackFcn(app, @FrequnciaSliderXValueChanging, true);
            app.FrequnciaSliderX.Value = 1;
            app.PertubaoPanel = uipanel(app.UIFigure);
            app.PertubaoPanel.Title = 'Pertubação';
            app.SliderX = uislider(app.PertubaoPanel);
            app.SliderX.Limits = [-5 5];
            app.SliderX.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.SliderX.ValueChangingFcn = createCallbackFcn(app, @PertubacaoSliderXValueChanging, true);
            app.EixoXLabel = uilabel(app.PertubaoPanel);
            app.EixoXLabel.Text = 'Eixo X';
            app.SliderY = uislider(app.PertubaoPanel);
            app.SliderY.Limits = [-5 5];
            app.SliderY.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.SliderY.ValueChangingFcn = createCallbackFcn(app, @SliderYValueChanging, true);
            app.EixoYLabel = uilabel(app.PertubaoPanel);
            app.EixoYLabel.Text = 'Eixo Y';
            app.ModelagemdeGanhosPanel = uipanel(app.UIFigure);
            app.ModelagemdeGanhosPanel.Title = 'Modelagem de Ganhos';
            app.AjusteGanhosAlocaodePolosKnob = uiknob(app.ModelagemdeGanhosPanel, 'continuous');
            app.AjusteGanhosAlocaodePolosKnob.Limits = [-8 10];
            app.AjusteGanhosAlocaodePolosKnob.ValueChangingFcn = createCallbackFcn(app, @AjusteGanhosAlocaodePolosKnobValueChanging, true);
            app.AjusteGanhosAlocaodePolosKnob.Value = 1;
            app.AjusteGanhosAlocaodePolosKnobLabel = uilabel(app.ModelagemdeGanhosPanel);
            app.AjusteGanhosAlocaodePolosKnobLabel.Text = 'Ajuste Ganhos Alocação de Polos';
            app.Switch = uiswitch(app.ModelagemdeGanhosPanel, 'slider');
            app.Switch.Items = {'Polos', 'LQR'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Polos';
            app.ModoClssicoLampLabel = uilabel(app.ModelagemdeGanhosPanel);
            app.ModoClssicoLampLabel.Text = 'Modo Clássico';
            app.ModoClssicoLamp = uilamp(app.ModelagemdeGanhosPanel);
            app.ModoLQRLampLabel = uilabel(app.ModelagemdeGanhosPanel);
            app.ModoLQRLampLabel.Text = 'Modo LQR';
            app.ModoLQRLamp = uilamp(app.ModelagemdeGanhosPanel);
            app.AjusteGanhosLQRKnobLabel = uilabel(app.ModelagemdeGanhosPanel);
            app.AjusteGanhosLQRKnobLabel.Text = 'Ajuste Ganhos LQR';
            app.AjusteGanhosLQRKnob = uiknob(app.ModelagemdeGanhosPanel, 'continuous');
            app.AjusteGanhosLQRKnob.Limits = [-8 10];
            app.AjusteGanhosLQRKnob.ValueChangingFcn = createCallbackFcn(app, @AjusteGanhosLQRKnobValueChanging, true);
            app.AjusteGanhosLQRKnob.Value = 1;
            app.ControledaPosiodaBolasobreoPratoPanel = uipanel(app.UIFigure);
            app.ControledaPosiodaBolasobreoPratoPanel.Title = 'Controle da Posição da Bola sobre o Prato';
            app.PosioYPanel = uipanel(app.UIFigure);
            app.PosioYPanel.Title = 'Posição Y';
            app.SwitchEntradaY = uiswitch(app.PosioYPanel, 'rocker');
            app.SwitchEntradaY.Items = {'Oscilatória', 'Constante'};
            app.SwitchEntradaY.ValueChangedFcn = createCallbackFcn(app, @SwitchEntradaYValueChanged, true);
            app.SwitchEntradaY.Value = 'Constante';
            app.ValorConstanteY = uieditfield(app.PosioYPanel, 'numeric');
            app.AmpltudeSenoidalY = uieditfield(app.PosioYPanel, 'numeric');
            app.EntradaConstanteSlider_2Label = uilabel(app.PosioYPanel);
            app.EntradaConstanteSlider_2Label.Text = 'Entrada Constante';
            app.EntradaConstanteSliderY = uislider(app.PosioYPanel);
            app.EntradaConstanteSliderY.Limits = [-0.6 0.6];
            app.EntradaConstanteSliderY.ValueChangingFcn = createCallbackFcn(app, @EntradaConstanteSliderYValueChanging, true);
            app.AmplitudeSlider_2Label = uilabel(app.PosioYPanel);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSliderY = uislider(app.PosioYPanel);
            app.AmplitudeSliderY.Limits = [-0.6 0.6];
            app.AmplitudeSliderY.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderYValueChanging, true);
            app.FrequnciaSliderLabel_2 = uilabel(app.PosioYPanel);
            app.FrequnciaSliderLabel_2.Text = 'Frequência';
            app.FrequnciaSliderY = uislider(app.PosioYPanel);
            app.FrequnciaSliderY.Limits = [0 2];
            app.FrequnciaSliderY.ValueChangingFcn = createCallbackFcn(app, @FrequnciaSliderYValueChanging, true);
            app.FrequnciaSliderY.Value = 1;
            app.FormasEspeciaisPanel = uipanel(app.UIFigure);
            app.FormasEspeciaisPanel.Title = 'Formas Especiais';
            app.Circulo = uibutton(app.FormasEspeciaisPanel, 'push');
            app.Circulo.ButtonPushedFcn = createCallbackFcn(app, @CirculoButtonPushed, true);
            app.Circulo.Text = '◯';
            app.Elipse = uibutton(app.FormasEspeciaisPanel, 'push');
            app.Elipse.ButtonPushedFcn = createCallbackFcn(app, @ElipseButtonPushed, true);
            app.Elipse.Text = {'0'; ''};
            app.Quadrado = uibutton(app.FormasEspeciaisPanel, 'push');
            app.Quadrado.ButtonPushedFcn = createCallbackFcn(app, @QuadradoButtonPushed, true);
            app.Quadrado.Text = '▢';
            app.Triangulo = uibutton(app.FormasEspeciaisPanel, 'push');
            app.Triangulo.ButtonPushedFcn = createCallbackFcn(app, @TrianguloButtonPushed, true);
            app.Triangulo.Text = '△';
            app.Infinito = uibutton(app.FormasEspeciaisPanel, 'push');
            app.Infinito.ButtonPushedFcn = createCallbackFcn(app, @InfinitoButtonPushed, true);
            app.Infinito.Text = '∞';
            app.RaioCirculo = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.RaioCirculo.Limits = [-0.6 0.6];
            app.EixoYElipse = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.EixoYElipse.Limits = [-0.6 0.6];
            app.EixoXElipse = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.EixoXElipse.Limits = [-0.6 0.6];
            app.EixoXInfinito = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.EixoXInfinito.Limits = [-0.6 0.6];
            app.EixoYInfinito = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.EixoYInfinito.Limits = [-0.6 0.6];
            app.LadoQuadrado = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.LadoQuadrado.Limits = [-0.6 0.6];
            app.LadoTriangulo = uieditfield(app.FormasEspeciaisPanel, 'numeric');
            app.LadoTriangulo.Limits = [-0.6 0.6];
            app.RaioLabel = uilabel(app.FormasEspeciaisPanel);
            app.RaioLabel.Text = 'Raio';
            app.EixoXLabel_2 = uilabel(app.FormasEspeciaisPanel);
            app.EixoXLabel_2.Text = 'Eixo X';
            app.EixoYLabel_2 = uilabel(app.FormasEspeciaisPanel);
            app.EixoYLabel_2.Text = 'Eixo Y';
            app.EixoYLabel_3 = uilabel(app.FormasEspeciaisPanel);
            app.EixoYLabel_3.Text = 'Eixo Y';
            app.EixoXLabel_3 = uilabel(app.FormasEspeciaisPanel);
            app.EixoXLabel_3.Text = 'Eixo X';
            app.Lado2Label = uilabel(app.FormasEspeciaisPanel);
            app.Lado2Label.Text = 'Lado / 2';
            app.Panel = uipanel(app.UIFigure);
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed2, true);
            app.ResetButton.Text = {'Reset'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppBolaPrato

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end