classdef AppBolaViga < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControlePosioBolaPanel         matlab.ui.container.Panel
        EntradaConstanteSliderLabel    matlab.ui.control.Label
        EntradaConstanteSlider         matlab.ui.control.Slider
        AmplitudeSliderLabel           matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        SwitchEntrada                  matlab.ui.control.RockerSwitch
        ValorConstante                 matlab.ui.control.NumericEditField
        AmpltudeSenoidal               matlab.ui.control.NumericEditField
        FrequnciaSliderLabel           matlab.ui.control.Label
        FrequnciaSlider                matlab.ui.control.Slider
        PertubaoPanel                  matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        ModelagemdeGanhosdoControladorPanel  matlab.ui.container.Panel
        AjusteGanhosLQRKnobLabel       matlab.ui.control.Label
        AjusteGanhosLQRKnob            matlab.ui.control.Knob
        AjusteGanhosAlocaodePolosKnob  matlab.ui.control.Knob
        AjusteGanhosAlocaodePolosKnobLabel  matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        ModoClassicoLampLabel          matlab.ui.control.Label
        ModoClassicoLamp               matlab.ui.control.Lamp
        ModoLQRLampLabel               matlab.ui.control.Label
        ModoLQRLamp                    matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set_param([bdroot,'/Senoide X'],'Amplitude', '0');
            set_param([bdroot,'/Constante X'],'Value', '0');
            set_param([bdroot,'/Senoide X'],'Frequency', '1');
            app.SwitchEntrada.Value = 'Constante';
            set_param([bdroot,'/On Off Senoide X'],'Gain','0');
            set_param([bdroot,'/On Off Constante X'],'Gain','1');
            app.ValorConstante.BackgroundColor = 'g';
            app.AmpltudeSenoidal.BackgroundColor = [0.65,0.65,0.65];
            set_param([bdroot,'/Pertubação X'],'Value', '0');
            set_param([bdroot,'/Escolha1'],'Gain', '0');
            set_param([bdroot,'/Escolha2'],'Gain', '0');
            set_param([bdroot,'/Escolha3'],'Gain', '1');
            set_param([bdroot,'/Escolha4'],'Gain', '1');
            set_param([bdroot,'/GanhoPolos1'],'Gain', '1');
            set_param([bdroot,'/GanhoPolos2'],'Gain', '1');
            set_param([bdroot,'/GanhoLQR'],'Gain', '1');
            set_param([bdroot,'/GanhoLQR1'],'Gain', '1');
            app.ModoClassicoLamp.Color = [1.00,1.00,0.00];
            app.ModoLQRLamp.Color = 'k';   
        end

        % Callback function: EntradaConstanteSlider, ValorConstante
        function EntradaConstanteSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Constante X'],'Value', num2str(changingValue));
            app.ValorConstante.Value=changingValue;
        end

        % Callback function: AmplitudeSlider, AmpltudeSenoidal
        function AmplitudeSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Senoide X'],'Amplitude', num2str(changingValue));
            app.AmpltudeSenoidal.Value=changingValue;
        end

        % Value changing function: FrequnciaSlider
        function FrequnciaSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Senoide X'],'Frequency', num2str(changingValue));
        end

        % Value changed function: SwitchEntrada
        function SwitchEntradaValueChanged(app, event)
            value = app.SwitchEntrada.Value;
            
            if strcmp(value,'Constante')
                set_param([bdroot,'/On Off Senoide X'],'Gain','0');
                set_param([bdroot,'/On Off Constante X'],'Gain','1');
                app.ValorConstante.BackgroundColor = 'g';
                app.AmpltudeSenoidal.BackgroundColor = [0.65,0.65,0.65];
            elseif strcmp(value,'Oscilatória')
                set_param([bdroot,'/On Off Senoide X'],'Gain','1');
                set_param([bdroot,'/On Off Constante X'],'Gain','0');
                app.ValorConstante.BackgroundColor = [0.65,0.65,0.65];
                app.AmpltudeSenoidal.BackgroundColor = 'g';
            end
        end

        % Value changing function: Slider
        function PertubacaoSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/Pertubação X'],'Value', num2str(changingValue));
        end

        % Value changing function: AjusteGanhosLQRKnob
        function AjusteGanhosLQRKnobValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/GanhoLQR'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoLQR1'],'Gain', num2str(changingValue));            
        end

        % Value changing function: AjusteGanhosAlocaodePolosKnob
        function AjusteGanhosAlocaodePolosKnobValueChanging(app, event)
            changingValue = event.Value;
            set_param([bdroot,'/GanhoPolos1'],'Gain', num2str(changingValue));
            set_param([bdroot,'/GanhoPolos2'],'Gain', num2str(changingValue));  
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp (app.Switch.Value,'LQR')
                set_param([bdroot,'/Escolha1'],'Gain', '1');
                set_param([bdroot,'/Escolha2'],'Gain', '1');
                set_param([bdroot,'/Escolha3'],'Gain', '0');
                set_param([bdroot,'/Escolha4'],'Gain', '0');
                app.ModoLQRLamp.Color = [1.00,1.00,0.00];
                app.ModoClassicoLamp.Color = 'k';
            else
                set_param([bdroot,'/Escolha1'],'Gain', '0');
                set_param([bdroot,'/Escolha2'],'Gain', '0');
                set_param([bdroot,'/Escolha3'],'Gain', '1');
                set_param([bdroot,'/Escolha4'],'Gain', '1');
                app.ModoClassicoLamp.Color = [1.00,1.00,0.00];
                app.ModoLQRLamp.Color = 'k';                
            end    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlePosioBolaPanel = uipanel(app.UIFigure);
            app.ControlePosioBolaPanel.Title = 'Controle Posição Bola';
            app.EntradaConstanteSliderLabel = uilabel(app.ControlePosioBolaPanel);
            app.EntradaConstanteSliderLabel.Text = 'Entrada Constante';
            app.EntradaConstanteSlider = uislider(app.ControlePosioBolaPanel);
            app.EntradaConstanteSlider.Limits = [-0.6 0.6];
            app.EntradaConstanteSlider.ValueChangingFcn = createCallbackFcn(app, @EntradaConstanteSliderValueChanging, true);
            app.AmplitudeSliderLabel = uilabel(app.ControlePosioBolaPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.ControlePosioBolaPanel);
            app.AmplitudeSlider.Limits = [-0.6 0.6];
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.SwitchEntrada = uiswitch(app.ControlePosioBolaPanel, 'rocker');
            app.SwitchEntrada.Items = {'Oscilatória', 'Constante'};
            app.SwitchEntrada.ValueChangedFcn = createCallbackFcn(app, @SwitchEntradaValueChanged, true);
            app.SwitchEntrada.Value = 'Oscilatória';
            app.ValorConstante = uieditfield(app.ControlePosioBolaPanel, 'numeric');
            app.ValorConstante.ValueChangedFcn = createCallbackFcn(app, @EntradaConstanteSliderValueChanging, true);
            app.AmpltudeSenoidal = uieditfield(app.ControlePosioBolaPanel, 'numeric');
            app.AmpltudeSenoidal.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            app.FrequnciaSliderLabel = uilabel(app.ControlePosioBolaPanel);
            app.FrequnciaSliderLabel.Text = 'Frequência';
            app.FrequnciaSlider = uislider(app.ControlePosioBolaPanel);
            app.FrequnciaSlider.Limits = [0 2];
            app.FrequnciaSlider.ValueChangingFcn = createCallbackFcn(app, @FrequnciaSliderValueChanging, true);
            app.FrequnciaSlider.Value = 1;
            app.PertubaoPanel = uipanel(app.UIFigure);
            app.PertubaoPanel.Title = 'Pertubação';
            app.Slider = uislider(app.PertubaoPanel);
            app.Slider.Limits = [-5 5];
            app.Slider.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @PertubacaoSliderValueChanging, true);
            app.ModelagemdeGanhosdoControladorPanel = uipanel(app.UIFigure);
            app.ModelagemdeGanhosdoControladorPanel.Title = 'Modelagem de Ganhos do Controlador';
            app.AjusteGanhosLQRKnobLabel = uilabel(app.ModelagemdeGanhosdoControladorPanel);
            app.AjusteGanhosLQRKnobLabel.Text = 'Ajuste Ganhos LQR';
            app.AjusteGanhosLQRKnob = uiknob(app.ModelagemdeGanhosdoControladorPanel, 'continuous');
            app.AjusteGanhosLQRKnob.Limits = [-8 10];
            app.AjusteGanhosLQRKnob.ValueChangingFcn = createCallbackFcn(app, @AjusteGanhosLQRKnobValueChanging, true);
            app.AjusteGanhosLQRKnob.Value = 1;
            app.AjusteGanhosAlocaodePolosKnob = uiknob(app.ModelagemdeGanhosdoControladorPanel, 'continuous');
            app.AjusteGanhosAlocaodePolosKnob.Limits = [-8 10];
            app.AjusteGanhosAlocaodePolosKnob.ValueChangingFcn = createCallbackFcn(app, @AjusteGanhosAlocaodePolosKnobValueChanging, true);
            app.AjusteGanhosAlocaodePolosKnob.Value = 1;
            app.AjusteGanhosAlocaodePolosKnobLabel = uilabel(app.ModelagemdeGanhosdoControladorPanel);
            app.AjusteGanhosAlocaodePolosKnobLabel.Text = 'Ajuste Ganhos Alocação de Polos';
            app.Switch = uiswitch(app.ModelagemdeGanhosdoControladorPanel, 'slider');
            app.Switch.Items = {'Polos', 'LQR'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Polos';
            app.ModoClassicoLampLabel = uilabel(app.ModelagemdeGanhosdoControladorPanel);
            app.ModoClassicoLampLabel.Text = 'Modo Classico';
            app.ModoClassicoLamp = uilamp(app.ModelagemdeGanhosdoControladorPanel);
            app.ModoLQRLampLabel = uilabel(app.ModelagemdeGanhosdoControladorPanel);
            app.ModoLQRLampLabel.Text = 'Modo LQR';
            app.ModoLQRLamp = uilamp(app.ModelagemdeGanhosdoControladorPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppBolaViga

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end